classdef DCmotor_AML_CD03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Competence                     matlab.ui.control.Label
        DCMotorControlDiscreteControlLabel  matlab.ui.control.Label
        MisceLogo                      matlab.ui.control.Image
        ConnecttoplatformLabel         matlab.ui.control.Label
        aSelecttheserialportLabel      matlab.ui.control.Label
        bPushConnectLabel              matlab.ui.control.Label
        cConnectionstateLabel          matlab.ui.control.Label
        AvailableportsListBox          matlab.ui.control.ListBox
        AvailableportsListBoxLabel     matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        StateLabel                     matlab.ui.control.Label
        DisconnectexitButton           matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MotorwarmupTab1                matlab.ui.container.Tab
        labeleTime1                    matlab.ui.control.Label
        comment101                     matlab.ui.control.Label
        comment102                     matlab.ui.control.Label
        eTime1                         matlab.ui.control.NumericEditField
        execute1                       matlab.ui.control.Button
        OpenloopresponseTab2           matlab.ui.container.Tab
        sampleTime2                    matlab.ui.control.NumericEditField
        TiempodeensayosLabel_51        matlab.ui.control.Label
        comment201                     matlab.ui.control.Label
        comment202                     matlab.ui.control.Label
        inputRef2                      matlab.ui.control.NumericEditField
        TiempodeensayosLabel_4         matlab.ui.control.Label
        eTime2                         matlab.ui.control.NumericEditField
        execute2                       matlab.ui.control.Button
        saveResults2                   matlab.ui.control.Button
        TiempodeensayosLabel_3         matlab.ui.control.Label
        figure2                        matlab.ui.control.UIAxes
        VelocityControlactionsTab3     matlab.ui.container.Tab
        controllerEq3_3                matlab.ui.control.Label
        sampleTime3                    matlab.ui.control.NumericEditField
        TiempodeensayosLabel_52        matlab.ui.control.Label
        comment301                     matlab.ui.control.Label
        comment302                     matlab.ui.control.Label
        TiempodeensayosLabel_6         matlab.ui.control.Label
        execute3                       matlab.ui.control.Button
        Kd3                            matlab.ui.control.NumericEditField
        Ki3                            matlab.ui.control.NumericEditField
        Kp3                            matlab.ui.control.NumericEditField
        inputRef3                      matlab.ui.control.NumericEditField
        eTime3                         matlab.ui.control.NumericEditField
        controllerLabel3               matlab.ui.control.Label
        labelKp3                       matlab.ui.control.Label
        labelKi3                       matlab.ui.control.Label
        labelKd3                       matlab.ui.control.Label
        TiempodeensayosLabel_9         matlab.ui.control.Label
        TiempodeensayosLabel_8         matlab.ui.control.Label
        TiempodeensayosLabel_7         matlab.ui.control.Label
        TiempodeensayosLabel_5         matlab.ui.control.Label
        saveResults3                   matlab.ui.control.Button
        figure3                        matlab.ui.control.UIAxes
        VelocityControlzerospolesTab4  matlab.ui.container.Tab
        controllerEq4_3                matlab.ui.control.Label
        sampleTime4                    matlab.ui.control.NumericEditField
        TiempodeensayosLabel_53        matlab.ui.control.Label
        comment401                     matlab.ui.control.Label
        execute4                       matlab.ui.control.Button
        Pi4                            matlab.ui.control.NumericEditField
        Ci4                            matlab.ui.control.NumericEditField
        P4                             matlab.ui.control.NumericEditField
        C4                             matlab.ui.control.NumericEditField
        K4                             matlab.ui.control.NumericEditField
        inputRef4                      matlab.ui.control.NumericEditField
        eTime4                         matlab.ui.control.NumericEditField
        controllerLabel4               matlab.ui.control.Label
        labelK4                        matlab.ui.control.Label
        labelC4                        matlab.ui.control.Label
        labelP4                        matlab.ui.control.Label
        labelCi4                       matlab.ui.control.Label
        labelPi4                       matlab.ui.control.Label
        TiempodeensayosLabel_32        matlab.ui.control.Label
        TiempodeensayosLabel_31        matlab.ui.control.Label
        TiempodeensayosLabel_30        matlab.ui.control.Label
        TiempodeensayosLabel_29        matlab.ui.control.Label
        TiempodeensayosLabel_28        matlab.ui.control.Label
        TiempodeensayosLabel_25        matlab.ui.control.Label
        TiempodeensayosLabel_14        matlab.ui.control.Label
        saveResults4                   matlab.ui.control.Button
        figure4                        matlab.ui.control.UIAxes
        PositionControlactionsTab5     matlab.ui.container.Tab
        controllerEq3_2                matlab.ui.control.Label
        inputRef5                      matlab.ui.control.NumericEditField
        TiempodeensayosLabel_54        matlab.ui.control.Label
        comment501                     matlab.ui.control.Label
        comment502                     matlab.ui.control.Label
        execute5                       matlab.ui.control.Button
        Kd5                            matlab.ui.control.NumericEditField
        Ki5                            matlab.ui.control.NumericEditField
        Kp5                            matlab.ui.control.NumericEditField
        sampleTime5                    matlab.ui.control.NumericEditField
        eTime5                         matlab.ui.control.NumericEditField
        controllerLabel5               matlab.ui.control.Label
        labelKp5                       matlab.ui.control.Label
        labelKi5                       matlab.ui.control.Label
        labelKd5                       matlab.ui.control.Label
        TiempodeensayosLabel_45        matlab.ui.control.Label
        TiempodeensayosLabel_44        matlab.ui.control.Label
        TiempodeensayosLabel_43        matlab.ui.control.Label
        saveResults5                   matlab.ui.control.Button
        TiempodeensayosLabel_26        matlab.ui.control.Label
        TiempodeensayosLabel_19        matlab.ui.control.Label
        figure5                        matlab.ui.control.UIAxes
        PositionControlzerospolesTab6  matlab.ui.container.Tab
        controllerEq4_2                matlab.ui.control.Label
        inputRef6                      matlab.ui.control.NumericEditField
        TiempodeensayosLabel_55        matlab.ui.control.Label
        comment601                     matlab.ui.control.Label
        execute6                       matlab.ui.control.Button
        Pi6                            matlab.ui.control.NumericEditField
        Ci6                            matlab.ui.control.NumericEditField
        P6                             matlab.ui.control.NumericEditField
        C6                             matlab.ui.control.NumericEditField
        K6                             matlab.ui.control.NumericEditField
        sampleTime6                    matlab.ui.control.NumericEditField
        eTime6                         matlab.ui.control.NumericEditField
        controllerLabel6               matlab.ui.control.Label
        labelK6                        matlab.ui.control.Label
        labelC6                        matlab.ui.control.Label
        labelP6                        matlab.ui.control.Label
        labelCi6                       matlab.ui.control.Label
        labelPi6                       matlab.ui.control.Label
        TiempodeensayosLabel_50        matlab.ui.control.Label
        TiempodeensayosLabel_49        matlab.ui.control.Label
        TiempodeensayosLabel_48        matlab.ui.control.Label
        TiempodeensayosLabel_47        matlab.ui.control.Label
        TiempodeensayosLabel_46        matlab.ui.control.Label
        TiempodeensayosLabel_37        matlab.ui.control.Label
        saveResults6                   matlab.ui.control.Button
        TiempodeensayosLabel_36        matlab.ui.control.Label
        figure6                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        sp 
        output 
        time 
        voltage 
        t
        experiment % Matrix of results
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConnectButton.Visible=true;
            app.aSelecttheserialportLabel.Visible = true;
            app.bPushConnectLabel.Visible = true;
            app.cConnectionstateLabel.Visible=true;
            app.StateLabel.Visible=true;
            app.AvailableportsListBox.Visible=true;
            app.AvailableportsListBox.Items=serialportlist('available');
            app.StateLabel.Text='Disconnected';
            app.execute1.Enable = 'off';
            app.execute2.Enable = 'off';
            app.execute3.Enable = 'off';
            app.execute4.Enable = 'off';
            app.execute5.Enable = 'off';
            app.execute6.Enable = 'off';

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %funcion para conectar con arduino
            puerto_com= app.AvailableportsListBox.Value;
            app.StateLabel.Text='Connecting...';
            try
                app.sp = serialport(puerto_com,115200,'Timeout',11);
                fopen(app.sp);
                fprintf(app.sp,'%i\n',1); 
                connection_read= fscanf(app.sp,'%i');
                if connection_read == 1 
                    app.StateLabel.Text='Connected';
                    app.execute1.Enable = 'on';
                    app.execute2.Enable = 'on';
                    app.execute3.Enable = 'on';
                    app.execute4.Enable = 'on';
                    app.execute5.Enable = 'on';
                    app.execute6.Enable = 'on';
                else
                    app.StateLabel.Text='Not possible to connect';

                end
            catch
                app.StateLabel.Text='Not possible to connect';
                warning('Not possible to connect to the serial port')
            end
        end

        % Button pushed function: DisconnectexitButton
        function DisconnectexitButtonPushed(app, event)
            delete(app);          
        end

        % Button pushed function: execute1
        function execute1Pushed(app, event)
            flush(app.sp); operationMode = 1;
            tsim=abs(app.eTime1.Value);
            fprintf(app.sp,'%i\n',operationMode);
            fprintf(app.sp,'%i\n',tsim);
            try
                warmupFlag = fscanf(app.sp,'%i');
            catch
                warning('Some data lost');
            end
        end

        % Button pushed function: execute2
        function execute2Pushed(app, event)
            % Operation mode 2: Open loop for identification
            flush(app.sp); operationMode = 2;
            tsim=abs(app.eTime2.Value);
            tsam=abs(app.sampleTime2.Value)*1000.0;
            v=app.inputRef2.Value;
            u=v;
            fprintf(app.sp,'%i\n',operationMode);
            fprintf(app.sp,'%f\n',u);
            fprintf(app.sp,'%i\n',tsim);
            fprintf(app.sp,'%i\n',tsam);
            app.output=[];
            app.time=[];
            app.voltage=[];
            app.t=0;
            while app.t<tsim
                dato_salida  = fscanf(app.sp,'%f');
                dato_tiempo  = fscanf(app.sp,'%f');
                dato_tension = fscanf(app.sp,'%f');
                app.output=[app.output dato_salida];
                app.time=[app.time dato_tiempo];
                app.voltage=[app.voltage dato_tension*12/255];                    
                app.t=dato_tiempo;
            end
            app.output = app.output;
            output_filt=sgolayfilt(app.output,1,13);
            app.experiment=[app.time; app.voltage; app.output; output_filt];
            k= mean(output_filt(end-20:end));
            app.figure2.Visible=true;
            plot(app.figure2,app.time,output_filt,'bo','MarkerSize',3,'LineWidth',2);
            hold(app.figure2,'on');
            [tesc,outputesc] = stairs(app.time,output_filt);
            plot(app.figure2,tesc,outputesc,'k:','LineWidth',1);
            xlabel(app.figure2,'t(s)');ylabel(app.figure2,'\omega (rad/s)');
            hold(app.figure2,'off');
       
        end

        % Button pushed function: execute3
        function execute3Pushed(app, event)
            % Operation mode 3: Velocity control (actions)
            flush(app.sp); operationMode = 3; isDigital = 1;
            tsim = abs(app.eTime3.Value);
            tsam = abs(app.sampleTime3.Value)*1000;
            vref = app.inputRef3.Value;
            kp   = app.Kp3.Value;
            ki   = app.Ki3.Value;
            kd   = app.Kd3.Value;
            fprintf(app.sp,'%i\n',operationMode);
            fprintf(app.sp,'%i\n',isDigital);
            fprintf(app.sp,'%f\n',vref);
            fprintf(app.sp,'%i\n',tsim);
            fprintf(app.sp,'%i\n',tsam);
            fprintf(app.sp,'%f\n',kp);
            fprintf(app.sp,'%f\n',ki);
            fprintf(app.sp,'%f\n',kd);
            app.output=[];
            app.time=[];
            app.voltage=[];
            app.t=0;
            while app.t<tsim
                dato_salida=fscanf(app.sp,'%f');
                dato_tiempo=fscanf(app.sp,'%f');
                dato_tension=fscanf(app.sp,'%f');
                app.output=[app.output dato_salida];
                app.time=[app.time dato_tiempo];
                app.voltage=[app.voltage dato_tension*12/255]; 
                app.t=dato_tiempo;
            end
            app.output = app.output;
            salida_filt=sgolayfilt(app.output,1,13);
            app.experiment=[app.time; app.voltage; app.output; salida_filt];
            app.figure3.Visible=true;
            
            plot(app.figure3,app.time,vref*ones(1,length(app.output)),'ro','MarkerSize',3,'LineWidth',2); 
            hold(app.figure3,'on');
            plot(app.figure3,app.time,salida_filt,'bo','MarkerSize',3,'LineWidth',2);    
            [tesc,outputesc] = stairs(app.time,salida_filt);
            plot(app.figure3,tesc,outputesc,'k:','LineWidth',1);
            
            
            xlabel(app.figure3,'t(s)');ylabel(app.figure3,'\omega(rad/s)');
            legend(app.figure3,'\omega_{ref}(t)','\omega(t)','location','best');
            hold(app.figure3,'off');
        end

        % Button pushed function: execute4
        function execute4Pushed(app, event)
            % Operation mode 5: Velocity control (zeros/poles)
            flush(app.sp); operationMode = 4; isDigital = 1;
            tsim=abs(app.eTime4.Value);
            tsam=abs(app.sampleTime4.Value)*1000;
            vref= app.inputRef4.Value;
            k = app.K4.Value;
            c =  app.C4.Value;
            p =   app.P4.Value;
            c_i = app.Ci4.Value;
            p_i = app.Pi4.Value;

            fprintf(app.sp,'%i\n',operationMode);
            fprintf(app.sp,'%i\n',isDigital);
            fprintf(app.sp,'%f\n',vref);
            fprintf(app.sp,'%i\n',tsim);
            fprintf(app.sp,'%i\n',tsam);
            fprintf(app.sp,'%f\n',k);
            fprintf(app.sp,'%f\n',c);
            fprintf(app.sp,'%f\n',p);
            fprintf(app.sp,'%f\n',c_i);
            fprintf(app.sp,'%f\n',p_i);
            app.output=[];
            app.time=[];
            app.voltage=[];
            app.t=0;
            while app.t<tsim
                dato_salida=fscanf(app.sp,'%f');
                dato_tiempo=fscanf(app.sp,'%f');
                dato_tension=fscanf(app.sp,'%f');
                app.output=[app.output dato_salida];
                app.time=[app.time dato_tiempo];
                app.voltage=[app.voltage dato_tension*12/255]; 
                app.t=dato_tiempo;               
            end
            app.output = app.output;
            salida_filt=sgolayfilt(app.output,1,13);
            app.experiment=[app.time; app.voltage; app.output; salida_filt];
            app.figure3.Visible=true;
            
            plot(app.figure4,app.time,vref*ones(1,length(app.output)),'ro','MarkerSize',3,'LineWidth',2);
            hold(app.figure4,'on');
            plot(app.figure4,app.time,salida_filt,'bo','MarkerSize',3,'LineWidth',2);    
            [tesc,outputesc] = stairs(app.time,salida_filt);
            plot(app.figure4,tesc,outputesc,'k:','LineWidth',1);

            xlabel(app.figure4,'t(s)'); ylabel(app.figure4,'\omega(rad/s)');
            legend(app.figure4,'\omega_{ref}(t)','\omega(t)','location','best');
            hold(app.figure4,'off');
        end

        % Button pushed function: execute5
        function execute5Pushed(app, event)
            % Operation mode 5: Position control (actions)
            flush(app.sp); operationMode = 5; isDigital = 1;
            tsim=abs(app.eTime5.Value);
            tsam=abs(app.sampleTime5.Value)*1000;
            pref=app.inputRef5.Value;
            kp = app.Kp5.Value;
            kd = app.Kd5.Value;
            ki = app.Ki5.Value;
            fopen(app.sp);
            fprintf(app.sp,'%i\n',operationMode);
            fprintf(app.sp,'%i\n',isDigital);
            fprintf(app.sp,'%f\n',pref);
            fprintf(app.sp,'%i\n',tsim);
            fprintf(app.sp,'%i\n',tsam);
            fprintf(app.sp,'%f\n',kp);
            fprintf(app.sp,'%f\n',ki);
            fprintf(app.sp,'%f\n',kd);
            app.output=[];
            app.time=[];
            app.voltage=[];
            app.t=0;
            while app.t<tsim
                dato_salida=fscanf(app.sp,'%f');
                dato_tiempo=fscanf(app.sp,'%f');
                dato_tension=fscanf(app.sp,'%f');
                app.output=[app.output dato_salida];
                app.time=[app.time dato_tiempo];
                app.voltage=[app.voltage dato_tension*12/255]; 
                app.t=dato_tiempo;
            end
            app.output = app.output;
            salida_filt=sgolayfilt(app.output,1,13);
            app.experiment=[app.time; app.voltage; app.output; salida_filt];
            app.figure5.Visible=true;

            plot(app.figure5,app.time,pref*ones(1,length(app.output)),'ro','MarkerSize',3,'LineWidth',2); 
            hold(app.figure5,'on');
            plot(app.figure5,app.time,salida_filt,'bo','MarkerSize',3,'LineWidth',2);        
            [tesc,outputesc] = stairs(app.time,salida_filt);
            plot(app.figure5,tesc,outputesc,'k:','LineWidth',1);

            xlabel(app.figure5,'t(s)'); ylabel(app.figure5,'\theta(rad)');
            legend(app.figure5,'\theta_{ref}(t)','\theta(t)','location','best');
            hold(app.figure5,'off');
        end

        % Button pushed function: execute6
        function execute6Pushed(app, event)
            % Operation mode 6: Position control (zeros/poles)
            flush(app.sp); operationMode = 6; isDigital = 1;
            tsim=abs(app.eTime6.Value);
            tsam=abs(app.sampleTime6.Value)*1000;
            pref= (app.inputRef6.Value); 
            k = app.K6.Value;
            c = app.C6.Value;
            p = app.P6.Value;
            c_i = app.Ci6.Value;
            p_i = app.Pi6.Value;
            
            fprintf(app.sp,'%i\n',operationMode);
            fprintf(app.sp,'%i\n',isDigital);
            fprintf(app.sp,'%f\n',pref);
            fprintf(app.sp,'%i\n',tsim);
            fprintf(app.sp,'%i\n',tsam);
            fprintf(app.sp,'%f\n',k);
            fprintf(app.sp,'%f\n',c);
            fprintf(app.sp,'%f\n',p);
            fprintf(app.sp,'%f\n',c_i);
            fprintf(app.sp,'%f\n',p_i);
            app.output=[];
            app.time=[];
            app.voltage=[];
            app.t=0;
            while app.t<tsim
                dato_salida=fscanf(app.sp,'%f');
                dato_tiempo=fscanf(app.sp,'%f');
                dato_tension=fscanf(app.sp,'%f');
                app.output=[app.output dato_salida];
                app.time=[app.time dato_tiempo];
                app.voltage=[app.voltage dato_tension*12/255];
                app.t=dato_tiempo;               
            end
            app.output = app.output;
            salida_filt=sgolayfilt(app.output,1,13);
            app.experiment=[app.time; app.voltage; app.output; salida_filt];
            app.figure6.Visible=true;

            plot(app.figure6,app.time,pref*ones(1,length(app.output)),'ro','MarkerSize',3,'LineWidth',2); 
            hold(app.figure6,'on');
            plot(app.figure6,app.time,salida_filt,'bo','MarkerSize',3,'LineWidth',2);        
            [tesc,outputesc] = stairs(app.time,salida_filt);

            plot(app.figure6,tesc,outputesc,'k:','LineWidth',1);
            xlabel(app.figure6,'t(s)');ylabel(app.figure6,'\theta(rad)');
            legend(app.figure6,'\theta_{ref}(t)','\theta(t)','location','best');
            hold(app.figure6,'off');
        end

        % Button pushed function: saveResults2
        function saveResults2Pushed(app, event)
            try
                [fn,pn]=uiputfile('*.txt');
                fname = [pn fn];
                fileID=fopen(fname,"w");
                fprintf(fileID,'%f %f %f %f\n',app.experiment);
                fclose(fileID);
            catch
                warning('The results have not been saved');
            end
            
        end

        % Button pushed function: saveResults3
        function saveResults3Pushed(app, event)
            try
                [fn,pn]=uiputfile('*.txt');
                fname = [pn fn];
                fileID=fopen(fname,"w");
                fprintf(fileID,'%f %f %f %f\n',app.experiment);
                fclose(fileID);
            catch
                warning('The results have not been saved');
            end
            
        end

        % Button pushed function: saveResults4
        function saveResults4Pushed(app, event)
            try
                [fn,pn]=uiputfile('*.txt');
                fname = [pn fn];
                fileID=fopen(fname,"w");
                fprintf(fileID,'%f %f %f %f\n',app.experiment);
                fclose(fileID);
            catch
                warning('The results have not been saved');
            end
            
        end

        % Button pushed function: saveResults5
        function saveResults5Pushed(app, event)
            try
                [fn,pn]=uiputfile('*.txt');
                fname = [pn fn];
                fileID=fopen(fname,"w");
                fprintf(fileID,'%f %f %f %f\n',app.experiment);
                fclose(fileID);
            catch
                warning('The results have not been saved');
            end
            
        end

        % Button pushed function: saveResults6
        function saveResults6Pushed(app, event)
            try
                [fn,pn]=uiputfile('*.txt');
                fname = [pn fn];
                fileID=fopen(fname,"w");
                fprintf(fileID,'%f %f %f %f\n',app.experiment);
                fclose(fileID);
            catch
                warning('The results have not been saved');
            end
            
        end

        % Button down function: figure2
        function figure2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotorwarmupTab1 = uitab(app.TabGroup);
            app.MotorwarmupTab1.Title = 'Motor warm up';
            app.execute1 = uibutton(app.MotorwarmupTab1, 'push');
            app.execute1.ButtonPushedFcn = createCallbackFcn(app, @execute1Pushed, true);
            app.execute1.Text = 'Warm up';
            app.eTime1 = uieditfield(app.MotorwarmupTab1, 'numeric');
            app.eTime1.Value = 10;
            app.comment102 = uilabel(app.MotorwarmupTab1);
            app.comment102.Text = 'Warming up the motor aids to obtain homogenous results.';
            app.comment101 = uilabel(app.MotorwarmupTab1);
            app.comment101.Text = 'Some dynamics parameters of the motor change with the temperature.';
            app.labeleTime1 = uilabel(app.MotorwarmupTab1);
            app.labeleTime1.Text = 'Warm up time (s):';
            app.OpenloopresponseTab2 = uitab(app.TabGroup);
            app.OpenloopresponseTab2.Title = 'Open loop response';
            app.figure2 = uiaxes(app.OpenloopresponseTab2);
            title(app.figure2, 'Open loop experiment')
            xlabel(app.figure2, 't(s)')
            ylabel(app.figure2, 'velocity (rad/s)')
            zlabel(app.figure2, 'Z')
            app.figure2.ButtonDownFcn = createCallbackFcn(app, @figure2ButtonDown, true);
            app.TiempodeensayosLabel_3 = uilabel(app.OpenloopresponseTab2);
            app.TiempodeensayosLabel_3.Text = 'Experiment time (s):';
            app.saveResults2 = uibutton(app.OpenloopresponseTab2, 'push');
            app.saveResults2.ButtonPushedFcn = createCallbackFcn(app, @saveResults2Pushed, true);
            app.saveResults2.Text = 'Save open loop results';
            app.execute2 = uibutton(app.OpenloopresponseTab2, 'push');
            app.execute2.ButtonPushedFcn = createCallbackFcn(app, @execute2Pushed, true);
            app.execute2.Text = 'Execute';
            app.eTime2 = uieditfield(app.OpenloopresponseTab2, 'numeric');
            app.eTime2.Value = 3;
            app.TiempodeensayosLabel_4 = uilabel(app.OpenloopresponseTab2);
            app.TiempodeensayosLabel_4.Text = 'Input Voltage (-12,12) V:';
            app.inputRef2 = uieditfield(app.OpenloopresponseTab2, 'numeric');
            app.inputRef2.Value = 10;
            app.comment202 = uilabel(app.OpenloopresponseTab2);
            app.comment202.Text = 'identify the transfer function of the DC- motor';
            app.comment201 = uilabel(app.OpenloopresponseTab2);
            app.comment201.Text = 'Exciting the motor with several values input voltage allows to ';
            app.TiempodeensayosLabel_51 = uilabel(app.OpenloopresponseTab2);
            app.TiempodeensayosLabel_51.Text = 'Sample time (ms)';
            app.sampleTime2 = uieditfield(app.OpenloopresponseTab2, 'numeric');
            app.sampleTime2.Value = 10;
            app.VelocityControlactionsTab3 = uitab(app.TabGroup);
            app.VelocityControlactionsTab3.Title = 'Velocity Control (actions)';
            app.figure3 = uiaxes(app.VelocityControlactionsTab3);
            title(app.figure3, 'Velocity control experiment')
            xlabel(app.figure3, 't(s)')
            ylabel(app.figure3, 'velocity (rad/s)')
            zlabel(app.figure3, 'Z')
            app.saveResults3 = uibutton(app.VelocityControlactionsTab3, 'push');
            app.saveResults3.ButtonPushedFcn = createCallbackFcn(app, @saveResults3Pushed, true);
            app.saveResults3.Text = 'Save velocity control results';
            app.TiempodeensayosLabel_5 = uilabel(app.VelocityControlactionsTab3);
            app.TiempodeensayosLabel_5.Text = 'Experiment time (s):';
            app.TiempodeensayosLabel_7 = uilabel(app.VelocityControlactionsTab3);
            app.TiempodeensayosLabel_7.Text = 'Proporcional constant,';
            app.TiempodeensayosLabel_8 = uilabel(app.VelocityControlactionsTab3);
            app.TiempodeensayosLabel_8.Text = 'Integral constant,';
            app.TiempodeensayosLabel_9 = uilabel(app.VelocityControlactionsTab3);
            app.TiempodeensayosLabel_9.Text = 'Derivative constant,';
            app.labelKd3 = uilabel(app.VelocityControlactionsTab3);
            app.labelKd3.Interpreter = 'latex';
            app.labelKd3.Text = '$k_d$';
            app.labelKi3 = uilabel(app.VelocityControlactionsTab3);
            app.labelKi3.Interpreter = 'latex';
            app.labelKi3.Text = '$k_i$';
            app.labelKp3 = uilabel(app.VelocityControlactionsTab3);
            app.labelKp3.Interpreter = 'latex';
            app.labelKp3.Text = '$k_p$';
            app.controllerLabel3 = uilabel(app.VelocityControlactionsTab3);
            app.controllerLabel3.Text = 'Controller';
            app.eTime3 = uieditfield(app.VelocityControlactionsTab3, 'numeric');
            app.eTime3.Value = 3;
            app.inputRef3 = uieditfield(app.VelocityControlactionsTab3, 'numeric');
            app.inputRef3.Value = 10;
            app.Kp3 = uieditfield(app.VelocityControlactionsTab3, 'numeric');
            app.Ki3 = uieditfield(app.VelocityControlactionsTab3, 'numeric');
            app.Kd3 = uieditfield(app.VelocityControlactionsTab3, 'numeric');
            app.execute3 = uibutton(app.VelocityControlactionsTab3, 'push');
            app.execute3.ButtonPushedFcn = createCallbackFcn(app, @execute3Pushed, true);
            app.execute3.Text = 'Execute';
            app.TiempodeensayosLabel_6 = uilabel(app.VelocityControlactionsTab3);
            app.TiempodeensayosLabel_6.Text = 'Velocity reference (rad/s):';
            app.comment302 = uilabel(app.VelocityControlactionsTab3);
            app.comment302.Text = 'written in term of the propotional, integral and derivative action';
            app.comment301 = uilabel(app.VelocityControlactionsTab3);
            app.comment301.Text = 'This operation mode allows you to control the DC-motor by means of a PID controller';
            app.TiempodeensayosLabel_52 = uilabel(app.VelocityControlactionsTab3);
            app.TiempodeensayosLabel_52.Text = 'Sample time (ms):';
            app.sampleTime3 = uieditfield(app.VelocityControlactionsTab3, 'numeric');
            app.sampleTime3.Value = 10;
            app.controllerEq3_3 = uilabel(app.VelocityControlactionsTab3);
            app.controllerEq3_3.Interpreter = 'latex';
            app.controllerEq3_3.Text = '$R(z) = k_p + k_i \frac{1}{1-z^{-1}}+k_d (1-z^{-1})';
            app.VelocityControlzerospolesTab4 = uitab(app.TabGroup);
            app.VelocityControlzerospolesTab4.Title = 'Velocity Control (zeros/poles)';
            app.figure4 = uiaxes(app.VelocityControlzerospolesTab4);
            title(app.figure4, 'Velocity control experiment')
            xlabel(app.figure4, 't(s)')
            ylabel(app.figure4, 'velocity (rad/s)')
            zlabel(app.figure4, 'Z')
            app.saveResults4 = uibutton(app.VelocityControlzerospolesTab4, 'push');
            app.saveResults4.ButtonPushedFcn = createCallbackFcn(app, @saveResults4Pushed, true);
            app.saveResults4.Text = 'Save velocity control results';
            app.TiempodeensayosLabel_14 = uilabel(app.VelocityControlzerospolesTab4);
            app.TiempodeensayosLabel_14.Text = 'Experiment time (s):';
            app.TiempodeensayosLabel_25 = uilabel(app.VelocityControlzerospolesTab4);
            app.TiempodeensayosLabel_25.Text = 'Velocity reference (rad/s):';
            app.TiempodeensayosLabel_28 = uilabel(app.VelocityControlzerospolesTab4);
            app.TiempodeensayosLabel_28.Text = 'Gain, ';
            app.TiempodeensayosLabel_29 = uilabel(app.VelocityControlzerospolesTab4);
            app.TiempodeensayosLabel_29.Text = 'zero, ';
            app.TiempodeensayosLabel_30 = uilabel(app.VelocityControlzerospolesTab4);
            app.TiempodeensayosLabel_30.Text = 'pole, ';
            app.TiempodeensayosLabel_31 = uilabel(app.VelocityControlzerospolesTab4);
            app.TiempodeensayosLabel_31.Text = 'zero, ';
            app.TiempodeensayosLabel_32 = uilabel(app.VelocityControlzerospolesTab4);
            app.TiempodeensayosLabel_32.Text = 'pole,';
            app.labelPi4 = uilabel(app.VelocityControlzerospolesTab4);
            app.labelPi4.Interpreter = 'latex';
            app.labelPi4.Text = '$p_i$';
            app.labelCi4 = uilabel(app.VelocityControlzerospolesTab4);
            app.labelCi4.Interpreter = 'latex';
            app.labelCi4.Text = '$c_i$';
            app.labelP4 = uilabel(app.VelocityControlzerospolesTab4);
            app.labelP4.Interpreter = 'latex';
            app.labelP4.Text = '$p$';
            app.labelC4 = uilabel(app.VelocityControlzerospolesTab4);
            app.labelC4.Interpreter = 'latex';
            app.labelC4.Text = '$c$';
            app.labelK4 = uilabel(app.VelocityControlzerospolesTab4);
            app.labelK4.Interpreter = 'latex';
            app.labelK4.Text = '$k$';
            app.controllerLabel4 = uilabel(app.VelocityControlzerospolesTab4);
            app.controllerLabel4.Text = 'Controller';
            app.eTime4 = uieditfield(app.VelocityControlzerospolesTab4, 'numeric');
            app.eTime4.Value = 3;
            app.inputRef4 = uieditfield(app.VelocityControlzerospolesTab4, 'numeric');
            app.inputRef4.Value = 10;
            app.K4 = uieditfield(app.VelocityControlzerospolesTab4, 'numeric');
            app.C4 = uieditfield(app.VelocityControlzerospolesTab4, 'numeric');
            app.P4 = uieditfield(app.VelocityControlzerospolesTab4, 'numeric');
            app.Ci4 = uieditfield(app.VelocityControlzerospolesTab4, 'numeric');
            app.Pi4 = uieditfield(app.VelocityControlzerospolesTab4, 'numeric');
            app.execute4 = uibutton(app.VelocityControlzerospolesTab4, 'push');
            app.execute4.ButtonPushedFcn = createCallbackFcn(app, @execute4Pushed, true);
            app.execute4.Text = 'Execute';
            app.comment401 = uilabel(app.VelocityControlzerospolesTab4);
            app.comment401.Text = 'This operation mode allows you to control the DC-motor by means of a PID controller defining its ceros and poles';
            app.TiempodeensayosLabel_53 = uilabel(app.VelocityControlzerospolesTab4);
            app.TiempodeensayosLabel_53.Text = 'Sample time (ms):';
            app.sampleTime4 = uieditfield(app.VelocityControlzerospolesTab4, 'numeric');
            app.sampleTime4.Value = 10;
            app.controllerEq4_3 = uilabel(app.VelocityControlzerospolesTab4);
            app.controllerEq4_3.Interpreter = 'latex';
            app.controllerEq4_3.Text = '$R(z)=k·\frac{z-c}{z-p}·\frac{z-c_i}{z-p_i} $';
            app.PositionControlactionsTab5 = uitab(app.TabGroup);
            app.PositionControlactionsTab5.Title = 'Position Control (actions)';
            app.figure5 = uiaxes(app.PositionControlactionsTab5);
            title(app.figure5, 'Position control experiment')
            xlabel(app.figure5, 't(s)')
            ylabel(app.figure5, 'position (rad)')
            zlabel(app.figure5, 'Z')
            app.TiempodeensayosLabel_19 = uilabel(app.PositionControlactionsTab5);
            app.TiempodeensayosLabel_19.Text = 'Experiment time (s):';
            app.TiempodeensayosLabel_26 = uilabel(app.PositionControlactionsTab5);
            app.TiempodeensayosLabel_26.Text = 'Sample time (ms):';
            app.saveResults5 = uibutton(app.PositionControlactionsTab5, 'push');
            app.saveResults5.ButtonPushedFcn = createCallbackFcn(app, @saveResults5Pushed, true);
            app.saveResults5.Text = 'Save position control results';
            app.TiempodeensayosLabel_43 = uilabel(app.PositionControlactionsTab5);
            app.TiempodeensayosLabel_43.Text = 'Proporcional constant,';
            app.TiempodeensayosLabel_44 = uilabel(app.PositionControlactionsTab5);
            app.TiempodeensayosLabel_44.Text = 'Integral constant,';
            app.TiempodeensayosLabel_45 = uilabel(app.PositionControlactionsTab5);
            app.TiempodeensayosLabel_45.Text = 'Derivative constant,';
            app.labelKd5 = uilabel(app.PositionControlactionsTab5);
            app.labelKd5.Interpreter = 'latex';
            app.labelKd5.Text = '$k_d$';
            app.labelKi5 = uilabel(app.PositionControlactionsTab5);
            app.labelKi5.Interpreter = 'latex';
            app.labelKi5.Text = '$k_i$';
            app.labelKp5 = uilabel(app.PositionControlactionsTab5);
            app.labelKp5.Interpreter = 'latex';
            app.labelKp5.Text = '$k_p$';
            app.controllerLabel5 = uilabel(app.PositionControlactionsTab5);
            app.controllerLabel5.Text = 'Controller';
            app.eTime5 = uieditfield(app.PositionControlactionsTab5, 'numeric');
            app.eTime5.Value = 3;
            app.sampleTime5 = uieditfield(app.PositionControlactionsTab5, 'numeric');
            app.sampleTime5.Value = 10;
            app.Kp5 = uieditfield(app.PositionControlactionsTab5, 'numeric');
            app.Ki5 = uieditfield(app.PositionControlactionsTab5, 'numeric');
            app.Kd5 = uieditfield(app.PositionControlactionsTab5, 'numeric');
            app.execute5 = uibutton(app.PositionControlactionsTab5, 'push');
            app.execute5.ButtonPushedFcn = createCallbackFcn(app, @execute5Pushed, true);
            app.execute5.Text = 'Execute';
            app.comment502 = uilabel(app.PositionControlactionsTab5);
            app.comment502.Text = 'written in term of the propotional, integral and derivative action';
            app.comment501 = uilabel(app.PositionControlactionsTab5);
            app.comment501.Text = 'This operation mode allows you to control the DC-motor by means of a PID controller';
            app.TiempodeensayosLabel_54 = uilabel(app.PositionControlactionsTab5);
            app.TiempodeensayosLabel_54.Text = 'Position reference (rad):';
            app.inputRef5 = uieditfield(app.PositionControlactionsTab5, 'numeric');
            app.inputRef5.Value = 10;
            app.controllerEq3_2 = uilabel(app.PositionControlactionsTab5);
            app.controllerEq3_2.Interpreter = 'latex';
            app.controllerEq3_2.Text = '$R(z) = k_p + k_i \frac{1}{1-z^{-1}}+k_d (1-z^{-1})';
            app.PositionControlzerospolesTab6 = uitab(app.TabGroup);
            app.PositionControlzerospolesTab6.Title = 'Position Control (zeros/poles)';
            app.figure6 = uiaxes(app.PositionControlzerospolesTab6);
            title(app.figure6, 'Position control experiment')
            xlabel(app.figure6, 't(s)')
            ylabel(app.figure6, 'position (rad)')
            zlabel(app.figure6, 'Z')
            app.TiempodeensayosLabel_36 = uilabel(app.PositionControlzerospolesTab6);
            app.TiempodeensayosLabel_36.Text = 'Experiment time (s):';
            app.saveResults6 = uibutton(app.PositionControlzerospolesTab6, 'push');
            app.saveResults6.ButtonPushedFcn = createCallbackFcn(app, @saveResults6Pushed, true);
            app.saveResults6.Text = 'Save position control results';
            app.TiempodeensayosLabel_37 = uilabel(app.PositionControlzerospolesTab6);
            app.TiempodeensayosLabel_37.Text = 'Sample time (ms)';
            app.TiempodeensayosLabel_46 = uilabel(app.PositionControlzerospolesTab6);
            app.TiempodeensayosLabel_46.Text = 'Gain, ';
            app.TiempodeensayosLabel_47 = uilabel(app.PositionControlzerospolesTab6);
            app.TiempodeensayosLabel_47.Text = 'zero, ';
            app.TiempodeensayosLabel_48 = uilabel(app.PositionControlzerospolesTab6);
            app.TiempodeensayosLabel_48.Text = 'pole, ';
            app.TiempodeensayosLabel_49 = uilabel(app.PositionControlzerospolesTab6);
            app.TiempodeensayosLabel_49.Text = 'zero, ';
            app.TiempodeensayosLabel_50 = uilabel(app.PositionControlzerospolesTab6);
            app.TiempodeensayosLabel_50.Text = 'pole,';
            app.labelPi6 = uilabel(app.PositionControlzerospolesTab6);
            app.labelPi6.Interpreter = 'latex';
            app.labelPi6.Text = '$p_i$';
            app.labelCi6 = uilabel(app.PositionControlzerospolesTab6);
            app.labelCi6.Interpreter = 'latex';
            app.labelCi6.Text = '$c_i$';
            app.labelP6 = uilabel(app.PositionControlzerospolesTab6);
            app.labelP6.Interpreter = 'latex';
            app.labelP6.Text = '$p$';
            app.labelC6 = uilabel(app.PositionControlzerospolesTab6);
            app.labelC6.Interpreter = 'latex';
            app.labelC6.Text = '$c$';
            app.labelK6 = uilabel(app.PositionControlzerospolesTab6);
            app.labelK6.Interpreter = 'latex';
            app.labelK6.Text = '$k$';
            app.controllerLabel6 = uilabel(app.PositionControlzerospolesTab6);
            app.controllerLabel6.Text = 'Controller';
            app.eTime6 = uieditfield(app.PositionControlzerospolesTab6, 'numeric');
            app.eTime6.Value = 3;
            app.sampleTime6 = uieditfield(app.PositionControlzerospolesTab6, 'numeric');
            app.sampleTime6.Value = 10;
            app.K6 = uieditfield(app.PositionControlzerospolesTab6, 'numeric');
            app.C6 = uieditfield(app.PositionControlzerospolesTab6, 'numeric');
            app.P6 = uieditfield(app.PositionControlzerospolesTab6, 'numeric');
            app.Ci6 = uieditfield(app.PositionControlzerospolesTab6, 'numeric');
            app.Pi6 = uieditfield(app.PositionControlzerospolesTab6, 'numeric');
            app.execute6 = uibutton(app.PositionControlzerospolesTab6, 'push');
            app.execute6.ButtonPushedFcn = createCallbackFcn(app, @execute6Pushed, true);
            app.execute6.Text = 'Execute';
            app.comment601 = uilabel(app.PositionControlzerospolesTab6);
            app.comment601.Text = 'This operation mode allows you to control the DC-motor by means of a PID controller defining its ceros and poles';
            app.TiempodeensayosLabel_55 = uilabel(app.PositionControlzerospolesTab6);
            app.TiempodeensayosLabel_55.Text = 'Position reference (rad):';
            app.inputRef6 = uieditfield(app.PositionControlzerospolesTab6, 'numeric');
            app.inputRef6.Value = 10;
            app.controllerEq4_2 = uilabel(app.PositionControlzerospolesTab6);
            app.controllerEq4_2.Interpreter = 'latex';
            app.controllerEq4_2.Text = '$R(z)=k·\frac{z-c}{z-p}·\frac{z-c_i}{z-p_i} $';
            app.DisconnectexitButton = uibutton(app.UIFigure, 'push');
            app.DisconnectexitButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectexitButtonPushed, true);
            app.DisconnectexitButton.Text = 'Disconnect & exit';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.AvailableportsListBoxLabel = uilabel(app.UIFigure);
            app.AvailableportsListBoxLabel.Text = 'Available ports';
            app.AvailableportsListBox = uilistbox(app.UIFigure);
            app.AvailableportsListBox.Value = 'Item 3';
            app.cConnectionstateLabel = uilabel(app.UIFigure);
            app.cConnectionstateLabel.Text = 'c. Connection state';
            app.bPushConnectLabel = uilabel(app.UIFigure);
            app.bPushConnectLabel.Text = 'b. Push ''Connect''';
            app.aSelecttheserialportLabel = uilabel(app.UIFigure);
            app.aSelecttheserialportLabel.Text = 'a. Select the serial port';
            app.ConnecttoplatformLabel = uilabel(app.UIFigure);
            app.ConnecttoplatformLabel.Text = '1. Connect to platform';
            app.MisceLogo = uiimage(app.UIFigure);
            app.MisceLogo.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.DCMotorControlDiscreteControlLabel = uilabel(app.UIFigure);
            app.DCMotorControlDiscreteControlLabel.Text = {'DC-Motor Control'; 'Discrete Control'};
            app.Competence = uilabel(app.UIFigure);
            app.Competence.Text = 'Competence: Control Engineering';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DCmotor_AML_CD03

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end